classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        MasaciarkakgEditField       matlab.ui.control.NumericEditField
        MasaciarkakgEditFieldLabel  matlab.ui.control.Label
        DugonicimEditField          matlab.ui.control.NumericEditField
        DugonicimEditFieldLabel     matlab.ui.control.Label
        wykres                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        length % Długość nici wachadła
        mass % Masa wachadła
        Timer % timer for plot updating
        % progress is an intager clamped on 100.
        progress
        % direction. if 0 forward, if not (expected 1) backward
        direction
        % range is the max number of steps for our animation
        range = 200
    end
    
    methods (Access = private)
        
        function  updatePlot(app)
            phi0=pi/3;
            r=app.length; % const

            x = -r*cos(phi0-pi/2):.01:r*cos(phi0-pi/2);
            y=-sqrt(r^2-x.^2)+r;

            phi=-phi0*cos(2*pi*app.progress/app.range)-pi/2; % wyprowadzone w zeszycie ;-)
            xPt = r*cos(phi);
            yPt = r*sin(phi)+r; % translacja do punktu zaczepienia

            plot(app.wykres,...
                x,y,'-',...
                [0,xPt],[r,yPt],'r-o');

            set(app.wykres,'DataAspectRatio', [1,1,1]);
            app.makeProgress()

        end
        
        function makeProgress(app)
            if app.direction == 0 
                if app.progress == app.range
                    app.progress = app.progress - 1;
                    app.direction = 1;
                    return
                end
                app.progress = app.progress + 1;
            else
                if app.progress == 0;
                    app.progress = app.progress + 1;
                    app.direction = 0;
                    return
                end
                app.progress = app.progress - 1;
            end
        end
        
        function startAnimation(app, FPS)
            if isequal(get(app.Timer, 'Running'), 'on')
                stop(app.Timer)
            end
            delete(app.Timer)
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period',1/FPS,'TimerFcn', @(~,~)updatePlot(app));
            start(app.Timer)
        end
        
        function refreshAnimation(app)
            %calculate new FPS
            g = 9.81; % const
            T = 2*pi*sqrt(app.length/g)
            app.startAnimation(app.range/T)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.progress = 0;
            app.direction = 0;
            app.length=1;
            app.refreshAnimation();
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.UIFigureCloseRequest(); 
        end

        % Value changed function: DugonicimEditField
        function DugonicimEditFieldValueChanged(app, event)
            app.length = app.DugonicimEditField.Value
        end

        % Value changed function: MasaciarkakgEditField
        function MasaciarkakgEditFieldValueChanged(app, event)
            app.mass = app.MasaciarkakgEditField.Value
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.wykres = uiaxes(app.GridLayout);
            title(app.wykres, 'Wachadło')
            xlabel(app.wykres, 'X')
            ylabel(app.wykres, 'Y')
            zlabel(app.wykres, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DugonicimEditFieldLabel = uilabel(app.GridLayout2);
            app.DugonicimEditFieldLabel.Text = 'Długość nici [m]';
            app.DugonicimEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DugonicimEditField.Limits = [0 Inf];
            app.DugonicimEditField.ValueChangedFcn = createCallbackFcn(app, @DugonicimEditFieldValueChanged, true);
            app.DugonicimEditField.Value = 1;
            app.MasaciarkakgEditFieldLabel = uilabel(app.GridLayout2);
            app.MasaciarkakgEditFieldLabel.Text = 'Masa ciężarka [kg]';
            app.MasaciarkakgEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MasaciarkakgEditField.Limits = [0 Inf];
            app.MasaciarkakgEditField.ValueChangedFcn = createCallbackFcn(app, @MasaciarkakgEditFieldValueChanged, true);
            app.MasaciarkakgEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end